classdef app_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SetupButton                matlab.ui.control.Button
        DealButton                 matlab.ui.control.Button
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ResetButton                matlab.ui.control.Button
        Joint1SliderLabel          matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        ESTOPLampLabel             matlab.ui.control.Label
        ESTOPLamp                  matlab.ui.control.Lamp
        ClearButton                matlab.ui.control.Button
        RollEditFieldLabel         matlab.ui.control.Label
        RollEditField              matlab.ui.control.NumericEditField
        PitchEditFieldLabel        matlab.ui.control.Label
        PitchEditField             matlab.ui.control.NumericEditField
        YawEditFieldLabel          matlab.ui.control.Label
        YawEditField               matlab.ui.control.NumericEditField
        Joint4SliderLabel          matlab.ui.control.Label
        Joint4Slider               matlab.ui.control.Slider
        Joint5SliderLabel          matlab.ui.control.Label
        Joint5Slider               matlab.ui.control.Slider
        Joint6SliderLabel          matlab.ui.control.Label
        Joint6Slider               matlab.ui.control.Slider
        PlanarLabel                matlab.ui.control.Label
        CartesianqlimLabel         matlab.ui.control.Label
        ESTOPButton                matlab.ui.control.Button
        RESUMEButton               matlab.ui.control.Button
        FlopButton                 matlab.ui.control.Button
        TurnButton                 matlab.ui.control.Button
        RiverButton                matlab.ui.control.Button
        CardMovementsLabel         matlab.ui.control.Label
        LightCurtainButton         matlab.ui.control.Button
        RemoveObjectButton         matlab.ui.control.Button
        qAnimateButton             matlab.ui.control.Button
        SafetyDemonstrationsLabel  matlab.ui.control.Label
        CollisionCheckButton       matlab.ui.control.Button
    end

% ! PLEASE NOTE !
% GUI examples and code were found on the following website:
% https://au.mathworks.com/matlabcentral/fileexchange/24861-41-complete-gui-examples
% examples 11 (stop for loop), 13 (slider change) and few others are referenced in this GUI 
    
    properties (Access = private)
        robot = Kinova;
        CH = getCardHolders;
        CD = getCardDispenser;
        cards = getCardsRMRC;
    end

    methods (Access = private)
        
        % When sliding each Joint slider, update the Joint movements in real-time
%         function pose = RealTimeUpdate(app)
%             Joint1 = app.Joint1Slider.Value;
%             Joint2 = app.Joint2Slider.Value;
%             Joint3 = app.Joint3Slider.Value;
%             Joint4 = app.Joint4Slider.Value;
%             Joint5 = app.Joint5Slider.Value;
%             Joint6 = app.Joint6Slider.Value;
%             
%             jointState = app.robot.fkine([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
%             
%             app.XEditField.Value = jointState(1,4);
%             app.YEditField.Value = jointState(2,4);
%             app.ZEditField.Value = jointState(3,4);
%             rpy = tr2rpy(jointState);
%             app.RollEditField.Value  = rpy(1);
%             app.PitchEditField.Value = rpy(2);
%             app.YawEditField.Value   = rpy(3);
            
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             close all
%             clear
%             clc
%             set(0, 'DefaultFigureWindowStyle','docked')
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            w = 2;
            workspace =  [-w w -w w 0 3];
            
            %generate & Plot table, arm & card holders
            surf([-2,-2;2,2],[-2,2;-2,2],[0.001,0.001;0.001,0.001],...
                'CData',imread('carpet.jpg'),'FaceColor','texturemap');
            
            table = getTable;
            robot = Kinova;
            CH = getCardHolders;
            CD = getCardDispenser;
            cards = getCardsRMRC;
            bottle = getBottle;
            
            Dennis = People(workspace, 'Dennis', transl(1.1, 0.4, 0.01) * trotz(pi));
            Mei = People(workspace, 'Mei', transl(-1.15, 0.55, 0.001) * trotz(-pi/2));
            Jack = People(workspace, 'Jack', transl(0.0, 1.15, 0.01));
            view(3); %view(2)
            
            q = zeros(1,6);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            close all
            clear
            clc
            set(0, 'DefaultFigureWindowStyle','docked')
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
%             while app.ESTOPButton.ButtonPushedFcn == true
%                 disp('E-Stop Pushed: Stopping Game');
%                 %uiwait();
%             end
                    app.robot.eStop = 1; 
                    app.ESTOPLamp.Color = 'r';
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
%             if RESUMEButtonPushed(app, @RESUMEButtonPushed) == true
%                 disp('Resuming Game');
%                 uiresume();
%             end
              app.robot.eStop = 0;
              app.ESTOPLamp.Color = 'g';
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            Joint1 = deg2rad(app.Joint1Slider.Value);
            Joint2 = deg2rad(app.Joint2Slider.Value);
            Joint3 = deg2rad(app.Joint3Slider.Value);
            Joint4 = deg2rad(app.Joint4Slider.Value);
            Joint5 = deg2rad(app.Joint5Slider.Value);
            Joint6 = deg2rad(app.Joint6Slider.Value);
            
            if app.Joint1Slider.Value
                app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
            end
            
%             RealTimeUpdate(app);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            Joint1 = deg2rad(app.Joint1Slider.Value);
            Joint2 = deg2rad(app.Joint2Slider.Value);
            Joint3 = deg2rad(app.Joint3Slider.Value);
            Joint4 = deg2rad(app.Joint4Slider.Value);
            Joint5 = deg2rad(app.Joint5Slider.Value);
            Joint6 = deg2rad(app.Joint6Slider.Value);
            
            if app.Joint2Slider.Value
                app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
            end
            
%             RealTimeUpdate(app);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            Joint1 = deg2rad(app.Joint1Slider.Value);
            Joint2 = deg2rad(app.Joint2Slider.Value);
            Joint3 = deg2rad(app.Joint3Slider.Value);
            Joint4 = deg2rad(app.Joint4Slider.Value);
            Joint5 = deg2rad(app.Joint5Slider.Value);
            Joint6 = deg2rad(app.Joint6Slider.Value);
            
            if app.Joint3Slider.Value
                app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
            end
            
%             RealTimeUpdate(app);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            Joint1 = deg2rad(app.Joint1Slider.Value);
            Joint2 = deg2rad(app.Joint2Slider.Value);
            Joint3 = deg2rad(app.Joint3Slider.Value);
            Joint4 = deg2rad(app.Joint4Slider.Value);
            Joint5 = deg2rad(app.Joint5Slider.Value);
            Joint6 = deg2rad(app.Joint6Slider.Value);
            
            if app.Joint4Slider.Value
                app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
            end
            
%             RealTimeUpdate(app);
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            Joint1 = deg2rad(app.Joint1Slider.Value);
            Joint2 = deg2rad(app.Joint2Slider.Value);
            Joint3 = deg2rad(app.Joint3Slider.Value);
            Joint4 = deg2rad(app.Joint4Slider.Value);
            Joint5 = deg2rad(app.Joint5Slider.Value);
            Joint6 = deg2rad(app.Joint6Slider.Value);
            
            if app.Joint5Slider.Value
                app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
            end
            
%             RealTimeUpdate(app);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            Joint1 = deg2rad(app.Joint1Slider.Value);
            Joint2 = deg2rad(app.Joint2Slider.Value);
            Joint3 = deg2rad(app.Joint3Slider.Value);
            Joint4 = deg2rad(app.Joint4Slider.Value);
            Joint5 = deg2rad(app.Joint5Slider.Value);
            Joint6 = deg2rad(app.Joint6Slider.Value);
            
            if app.Joint6Slider.Value
                app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
            end
            
%             RealTimeUpdate(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            Joint1 = 0;
            app.Joint1Slider.Value = 0;
            app.XEditField.Value = 0;
            Joint2 = 0;
            app.Joint2Slider.Value = 0;
            app.YEditField.Value = 0;
            Joint3 = 0;
            app.Joint3Slider.Value = 0;
            app.ZEditField.Value = 0;
            Joint4 = 0;
            app.Joint4Slider.Value = 0;
            app.RollEditField.Value = 0;
            Joint5 = 0;
            app.Joint5Slider.Value = 0;
            app.PitchEditField.Value = 0;
            Joint6 = 0;
            app.Joint6Slider.Value = 0;
            app.YawEditField.Value = 0;
            
            app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6]);
            
%             RealTimeUpdate(app);
        end

        % Button pushed function: DealButton
        function DealButtonPushed(app, event)
            dealPlayerCards(app.robot,app.cards,app.CH);
        end

        % Button pushed function: FlopButton
        function FlopButtonPushed(app, event)
            theFlop(app.robot,app.cards,app.CH,app.CD);
        end

        % Button pushed function: TurnButton
        function TurnButtonPushed(app, event)
            theTurn(app.robot,app.cards,app.CH,app.CD);
        end

        % Button pushed function: RiverButton
        function RiverButtonPushed(app, event)
            theRiver(app.robot,app.cards,app.CH,app.CD);
        end

        % Button pushed function: LightCurtainButton
        function LightCurtainButtonPushed(app, event)
            % LIGHT CURTAIN-------------------------------------------
            % Create sphere
            sphereCenter = [0,0,0.95];
            radius = 0.84;
            [X,Y,Z] = sphere(10);
            X = X * radius + sphereCenter(1);
            Y = Y * radius + sphereCenter(2);
            Z = Z * radius + sphereCenter(3);
            
            % Plot it
            % Plot point cloud
            points = [X(:),Y(:),Z(:)];
            spherePc_h = plot3(points(:,1),points(:,2),points(:,3),'r.');
            pause
            delete (spherePc_h)
        end

        % Button pushed function: RemoveObjectButton
        function RemoveObjectButtonPushed(app, event)
%             app.robot = robot1;
            cardNum = 2;
            app.robot.eStop = 0;
            disp('Removing Object from Operation Zone, please wait for Dealer Bot to recommence');
            pause(0.5);
            bottle = getBottle;
            pause(3);
            grabNextCard(app.robot,app.cards,cardNum);
            quit;
        end

        % Button pushed function: qAnimateButton
        function qAnimateButtonPushed(app, event)
            X = rad2deg(app.XEditField.Value);
            Y = rad2deg(app.YEditField.Value);
            Z = rad2deg(app.ZEditField.Value);
            Roll = rad2deg(app.RollEditField.Value);
            Pitch = rad2deg(app.PitchEditField.Value);
            Yaw = rad2deg(app.YawEditField.Value);
            
            qPose = transl(X,Y,Z)*rpy2tr(Roll,Pitch,Yaw);
            
            qAnimate = app.robot.model.ikcon(qPose);
            app.robot.model.animate(qAnimate);
        end

        % Button pushed function: CollisionCheckButton
        function CollisionCheckButtonPushed(app, event)
            CollisionDetectionTest(app.robot,app.cards,app.CH,cardNum);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.DealButton = uibutton(app.UIFigure, 'push');
            app.DealButton.ButtonPushedFcn = createCallbackFcn(app, @DealButtonPushed, true);
            app.DealButton.Text = 'Deal';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-154 154];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-150 150];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-150 150];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.ESTOPLampLabel = uilabel(app.UIFigure);
            app.ESTOPLampLabel.Text = 'E-STOP';
            app.ESTOPLamp = uilamp(app.UIFigure);
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-149 149];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-145 145];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-149 149];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.PlanarLabel = uilabel(app.UIFigure);
            app.PlanarLabel.Text = 'Planar';
            app.CartesianqlimLabel = uilabel(app.UIFigure);
            app.CartesianqlimLabel.Text = 'Cartesian (qlim)';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.FlopButton = uibutton(app.UIFigure, 'push');
            app.FlopButton.ButtonPushedFcn = createCallbackFcn(app, @FlopButtonPushed, true);
            app.FlopButton.Text = 'Flop';
            app.TurnButton = uibutton(app.UIFigure, 'push');
            app.TurnButton.ButtonPushedFcn = createCallbackFcn(app, @TurnButtonPushed, true);
            app.TurnButton.Text = 'Turn';
            app.RiverButton = uibutton(app.UIFigure, 'push');
            app.RiverButton.ButtonPushedFcn = createCallbackFcn(app, @RiverButtonPushed, true);
            app.RiverButton.Text = 'River';
            app.CardMovementsLabel = uilabel(app.UIFigure);
            app.CardMovementsLabel.Text = 'Card Movements';
            app.LightCurtainButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainButtonPushed, true);
            app.LightCurtainButton.Text = 'Light Curtain';
            app.RemoveObjectButton = uibutton(app.UIFigure, 'push');
            app.RemoveObjectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveObjectButtonPushed, true);
            app.RemoveObjectButton.Text = 'Remove Object';
            app.qAnimateButton = uibutton(app.UIFigure, 'push');
            app.qAnimateButton.ButtonPushedFcn = createCallbackFcn(app, @qAnimateButtonPushed, true);
            app.qAnimateButton.Text = 'qAnimate';
            app.SafetyDemonstrationsLabel = uilabel(app.UIFigure);
            app.SafetyDemonstrationsLabel.Text = 'Safety Demonstrations';
            app.CollisionCheckButton = uibutton(app.UIFigure, 'push');
            app.CollisionCheckButton.ButtonPushedFcn = createCallbackFcn(app, @CollisionCheckButtonPushed, true);
            app.CollisionCheckButton.Text = 'Collision Check';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end